classdef seq_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        OpenSEQfileButton         matlab.ui.control.Button
        RWDButton                 matlab.ui.control.Button
        PlayPauseButton           matlab.ui.control.Button
        FFWDButton                matlab.ui.control.Button
        CurrentFrameSpinnerLabel  matlab.ui.control.Label
        CurrentFrameSpinner       matlab.ui.control.Spinner
        FrameCountEditFieldLabel  matlab.ui.control.Label
        FrameCountEditField       matlab.ui.control.NumericEditField
        Slider                    matlab.ui.control.Slider
        RightPanel                matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        Image                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        seq_file_name % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenSEQfileButton
        function OpenSEQfileButtonPushed(app, event)
            [fn,pn]=uigetfile('*.seq');
            app.seq_file_name=''
            seq_header=seq.readNorpixSeqHeader();
            nframes=seq_header.AllocatedFrames;
            app.Slider.Limits(2)=nframes;
            app.CurrentFrameSpinner.Limits(2)=nframes;
            app.Image=seq.readNorpixSeqImage()
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {280, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.OpenSEQfileButton = uibutton(app.GridLayout3, 'push');
            app.OpenSEQfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSEQfileButtonPushed, true);
            app.OpenSEQfileButton.Text = 'Open SEQ file';
            app.RWDButton = uibutton(app.GridLayout3, 'push');
            app.RWDButton.Text = 'RWD';
            app.PlayPauseButton = uibutton(app.GridLayout3, 'push');
            app.PlayPauseButton.Text = 'Play/Pause';
            app.FFWDButton = uibutton(app.GridLayout3, 'push');
            app.FFWDButton.Text = 'FFWD';
            app.CurrentFrameSpinnerLabel = uilabel(app.GridLayout3);
            app.CurrentFrameSpinnerLabel.Text = 'Current Frame';
            app.CurrentFrameSpinner = uispinner(app.GridLayout3);
            app.FrameCountEditFieldLabel = uilabel(app.GridLayout3);
            app.FrameCountEditFieldLabel.Text = 'Frame Count';
            app.FrameCountEditField = uieditfield(app.GridLayout3, 'numeric');
            app.Slider = uislider(app.GridLayout3);
            app.Slider.Limits = [1 100];
            app.Slider.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seq_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end